classdef matgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        ControlsPanel           matlab.ui.container.Panel
        EnableSpinningCheckBox  matlab.ui.control.CheckBox
        StopButton              matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        SourcePanel             matlab.ui.container.Panel
        BrowseButton            matlab.ui.control.Button
        FilePathEditField       matlab.ui.control.EditField
        FilePathEditFieldLabel  matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Open a file browser dialog to select a file
            [file, path] = uigetfile({'*.*', 'All Files (*.*)'}, 'Select a File');
            
            % Check if the user selected a file (i.e., they didn't cancel the dialog)
            if file ~= 0
                % Construct the full file path
                fullFilePath = fullfile(path, file);
                
                % Set the file path in the FilePathEditField
                app.FilePathEditField.Value = fullFilePath;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            [y, fs] = audioread(app.FilePathEditField.Value);
            stop = 0;
            vumeter(y, fs, app.UIAxes);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop = 1;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {562, 562};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SourcePanel = uipanel(app.LeftPanel);
            app.SourcePanel.Title = 'Source';
            app.FilePathEditFieldLabel = uilabel(app.SourcePanel);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.SourcePanel, 'text');
            app.BrowseButton = uibutton(app.SourcePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ControlsPanel = uipanel(app.LeftPanel);
            app.ControlsPanel.Title = 'Controls';
            app.PlayButton = uibutton(app.ControlsPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.EnableSpinningCheckBox = uicheckbox(app.ControlsPanel);
            app.EnableSpinningCheckBox.Text = 'Enable Spinning';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Audio Vase')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end